classdef app_regressao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        R2Table                      matlab.ui.control.Table
        FTestTable                   matlab.ui.control.Table
        CoeficientesdoModeloLabel    matlab.ui.control.Label
        ModelCoefficientsTable       matlab.ui.control.Table
        PasteTextArea                matlab.ui.control.TextArea
        ColeInputsAquiTextAreaLabel  matlab.ui.control.Label
        InitialConditionsTable       matlab.ui.control.Table
        BoundsTable                  matlab.ui.control.Table
        FactorSelectionGroup         matlab.ui.container.ButtonGroup
        ThreeFactorsRadioButton      matlab.ui.control.RadioButton
        TwoFactorsRadioButton        matlab.ui.control.RadioButton
        LimparButton                 matlab.ui.control.Button
        TabeladeinputsLabel          matlab.ui.control.Label
        InputTable                   matlab.ui.control.Table
        GerarPlanejamentoButton      matlab.ui.control.Button
        CriarModeloButton            matlab.ui.control.Button
        CondiesiniciaisLabel         matlab.ui.control.Label
        LimitesLabel                 matlab.ui.control.Label
        graficoResiduos              matlab.ui.control.UIAxes
        grafico                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotModelSurface2f(app, coef_details, lower_bounds, upper_bounds, optimal_point, optimal_Y)
            % Cria uma grade de pontos usando os limites inferiores e superiores
            [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 50), ...
                                        linspace(lower_bounds(2), upper_bounds(2), 50));
    
            % Avalia a função do modelo nos pontos da grade
            YGrid = coef_details(1) + coef_details(2)*X1Grid + coef_details(3)*X2Grid + ...
                    coef_details(4)*X1Grid.^2 + coef_details(5)*X2Grid.^2 + coef_details(6)*X1Grid.*X2Grid;

            % Toma limite superior e inferior 
            Ysup = max(max(YGrid));
            Yinf = min(min(YGrid));

            % Toma níveis para as curvas de nível
            resolution = 10;
            levels = linspace(Yinf, Ysup, resolution)';
    
            % Plotagem da superfície no componente UIAxes
            surf(app.grafico, X1Grid, X2Grid, YGrid);
            xlabel(app.grafico, 'X1');
            ylabel(app.grafico, 'X2');
            zlabel(app.grafico, 'Y');
            title(app.grafico, 'Superfície de resposta gerada pelo modelo');
            colormap(app.grafico, 'hsv');
            %surfaceObject.EdgeColor = 'none';
            hold(app.grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
            contour3(app.grafico, X1Grid, X2Grid, YGrid, levels, 'k', 'LineWidth', 2);
            % PLotagem do ponto ótimo
            plot3(app.grafico, optimal_point(1), optimal_point(2), optimal_Y, 'b.', 'MarkerSize', 30, 'LineWidth', 6);
            hold(app.grafico, 'off'); % Desliga o modo hold
        end

        function plotResiduals(app, Residuals)
            if min(Residuals(:,2)) < 0
                a = 2*min(Residuals(:,2));
            else
                a = -1*max(Residuals(:,2));
            end
            if max(Residuals(:,2)) > 0
                b = 2*max(Residuals(:,2));
            else
                b = -1*min(Residuals(:,2));
            end
            A = [a, b];
            B = [0 0];

            % Plotar os pontos no componente graficoResiduos
            cla(app.graficoResiduos); % Limpar o gráfico existente
            hold(app.graficoResiduos, 'on');
            plot(app.graficoResiduos, Residuals(:,2), Residuals(:,1) , 'r.', 'MarkerSize', 8);
            plot(app.graficoResiduos, A, B, 'b');
            hold(app.graficoResiduos, 'off')

            xlabel(app.graficoResiduos, 'Y');
            ylabel(app.graficoResiduos, 'Resíduos');
            grid(app.graficoResiduos, 'on');
        end

        function updateModelCoefficientsTable(app, coef_details, num_factors)
            % coef_details: Matriz contendo [coeficiente, erro padrão, valor p]
            % num_factors: Número de fatores (2 ou 3)
    
            if num_factors == 2
                % Para 2 fatores, temos 6 coeficientes (b0, b1, b2, b3, b4, b5)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2', 'X1^2', 'X2^2', 'X1*X2'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:6, 1)';  % Coeficientes
                    coef_details(1:6, 2)';  % Erro padrão
                    coef_details(1:6, 3)';  % Valor p
                ];
                
            elseif num_factors == 3
                % Para 3 fatores, temos 10 coeficientes (b0, b1, b2, b3, b4, b5, b6, b7, b8, b9)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2', 'X3', 'X1^2', 'X2^2', 'X3^2', 'X1*X2', 'X1*X3', 'X2*X3'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:10, 1)';  % Coeficientes
                    coef_details(1:10, 2)';  % Erro padrão
                    coef_details(1:10, 3)';  % Valor p
                ];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarModeloButton
        function CriarModeloButtonPushed2(app, event)
        
        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;

        % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores
   

        % Obter os valores da tabela (X1, X2, [X3]) e Y
        data = app.InputTable.Data;

        selectedButton = app.FactorSelectionGroup.SelectedObject;

        if selectedButton == app.TwoFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            Y = data(:, 3);  % Coluna Y
        
            % Chamar a função para 2 fatores
            [optimal_point, optimal_Y, coef_details, R2_vals, F_test, residuals] = gerarModelo2f(X1, X2, Y, IC, lower_bounds, upper_bounds);
            
            % Plota superfície
            plotModelSurface2f(app, coef_details(:,1), lower_bounds, upper_bounds, optimal_point, optimal_Y);
            updateModelCoefficientsTable(app, coef_details, 2);
            
            % Plota resíduos
            plotResiduals(app, residuals);

            % Mostra valores para o teste F
            app.FTestTable.ColumnName = {'Teste F'};
            app.FTestTable.RowName = {'Valor F', 'Valor p'};
            app.FTestTable.Data = [F_test(1); F_test(2)];

            % Mostra os valores de R^2
            app.R2Table.ColumnName = {'Valores de R2'};
            app.R2Table.RowName = {'R2', 'R2 ajustado'};
            app.R2Table.Data = [R2_vals(1); R2_vals(2)];

        elseif selectedButton == app.ThreeFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            X3 = data(:, 3); % Coluna X3
            Y = data(:, 4);  % Coluna Y
        
            % Chamar a função para 3 fatores
            [optimal_point, optimal_Y, coef_details, R2_vals, F_test, residuals] = gerarModelo3f(X1, X2, X3, Y, IC, lower_bounds, upper_bounds);
            % Plota superfície
            ...
            updateModelCoefficientsTable(app, coef_details, 3);
            
            % Plota resíduos
            plotResiduals(app, residuals);

            % Mostra valores para o teste F
            app.FTestTable.ColumnName = {'Teste F'};
            app.FTestTable.RowName = {'Valor F', 'Valor p'};
            app.FTestTable.Data = [F_test(1); F_test(2)];

            % Mostra os valores de R^2
            app.R2Table.ColumnName = {'Valores de R2'};
            app.R2Table.RowName = {'R2', 'R2 ajustado'};
            app.R2Table.Data = [R2_vals(1); R2_vals(2)];

        end
        end

        % Button pushed function: GerarPlanejamentoButton
        function GerarPlanejamentoButtonPushed(app, event)
       % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

        % Verificar se os limites são NaN
        if any(isnan(lower_bounds)) || any(isnan(upper_bounds))
            uialert(app.UIFigure, 'Verifique os limites inseridos. Os limites devem ser números válidos.', 'Erro nos Limites');
            return;
        end
        
        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;
        IC = (lower_bounds + upper_bounds) / 2;

        selectedButton = app.FactorSelectionGroup.SelectedObject;
        
        if selectedButton == app.TwoFactorsRadioButton
            % Calcular os pontos ao longo das bordas (8 pontos em um quadrado)
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            star_points = [
                am - a/sqrt(2), bm - b/sqrt(2);
                am + a/sqrt(2), bm - b/sqrt(2);
                am - a/sqrt(2), bm + b/sqrt(2);
                am + a/sqrt(2), bm + b/sqrt(2);
                IC(1), lower_bounds(2);
                IC(1), upper_bounds(2);
                lower_bounds(1), IC(2);
                upper_bounds(1), IC(2);
            ];
    
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
            % Plotar os pontos no componente UIAxes
            cla(app.grafico); % Limpar o gráfico existente
            hold(app.grafico, 'on');
            plot(app.grafico, star_points(:,1), star_points(:,2), 'bo', 'MarkerSize', 8, 'LineWidth', 2); % Pontos nas bordas
            plot(app.grafico, center_points_triplicados(:,1), center_points_triplicados(:,2), 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Pontos no centro
            hold(app.grafico, 'off');
        
            xlabel(app.grafico, 'X1');
            ylabel(app.grafico, 'X2');
            title(app.grafico, 'Planejamento');
            grid(app.grafico, 'on');
        elseif selectedButton == app.ThreeFactorsRadioButton
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            c = (upper_bounds(3) - lower_bounds(3))/2;
            a2 = (1/sqrt(3))*a;
            b2 = (1/sqrt(3))*b;
            c2 = (1/sqrt(3))*c;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            cm = (upper_bounds(3) + lower_bounds(3))/2;
            star_points = [
                am + a, bm, cm;
                am - a, bm, cm;
                am, bm + b, cm;
                am, bm - b, cm;
                am, bm, cm + c,
                am, bm, cm - c;
                am + a, bm + b, cm + c;
                am - a, bm + b, cm + c;
                am + a, bm - b, cm + c;
                am + a, bm + b, cm - c;
                am - a, bm - b, cm + c;
                am - a, bm + b, cm - c;
                am + a, bm - b, cm - c;
                am - a, bm - b, cm - c;
            ];
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
            % Limpar o gráfico anterior e plotar os novos pontos
            cla(app.grafico);  % Limpa o gráfico 3D anterior
            hold(app.grafico, 'on');
            
            % Plotar os pontos estrela em azul
            plot3(app.grafico, star_points(:,1), star_points(:,2), star_points(:,3), 'bo', 'MarkerSize', 8, 'LineWidth', 2);
            
            % Plotar o ponto central triplicado em vermelho
            plot3(app.grafico, center_points_triplicados(:,1), center_points_triplicados(:,2), center_points_triplicados(:,3), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            
            % Adicionar rótulos
            xlabel(app.grafico, 'X1');
            ylabel(app.grafico, 'X2');
            zlabel(app.grafico, 'X3');
            title(app.grafico, 'Planejamento');
            grid(app.grafico, 'on');
            hold(app.grafico, 'off');
        end
        
        end

        % Selection changed function: FactorSelectionGroup
        function FactorSelectionGroupSelectionChanged(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
                
            if selectedButton == app.TwoFactorsRadioButton
            % Configurar a tabela para 2 fatores (X1 e X2) + Y
            app.InputTable.ColumnName = {'X1', 'X2', 'Y'};
            app.InputTable.Data = zeros(11, 3); % Ajustar número de colunas para 3
            % Configurar a tabela de condições iniciais para 2 fatores (X1 e X2)
            app.InitialConditionsTable.ColumnName = {'X1', 'X2'};
            app.InitialConditionsTable.Data = zeros(1, 2); % 1 linha para condições iniciais
            app.BoundsTable.ColumnName = {'X1', 'X2'};
            app.BoundsTable.RowName = {'Inferiores', 'Superiores'};
            app.BoundsTable.Data = zeros(2, 2); % 2 linhas: 1 para limites inferiores, 1 para superiores
            elseif selectedButton == app.ThreeFactorsRadioButton
            % Configurar a tabela para 3 fatores (X1, X2 e X3) + Y
            app.InputTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.InputTable.Data = zeros(17, 4); % Ajustar número de colunas para 4
            % Configurar a tabela de condições iniciais para 3 fatores (X1, X2 e X3)
            app.InitialConditionsTable.ColumnName = {'X1', 'X2', 'X3'};
            app.InitialConditionsTable.Data = zeros(1, 3); % 1 linha para condições iniciais
            % Configurar a tabela de limites para 3 fatores (X1, X2 e X3)
            app.BoundsTable.ColumnName = {'X1', 'X2', 'X3'};
            app.BoundsTable.RowName = {'Inferiores', 'Superiores'};
            app.BoundsTable.Data = zeros(2, 3); % 2 linhas: 1 para limites inferiores, 1 para superiores
            end
        
       
        end

        % Value changed function: PasteTextArea
        function PasteTextAreaValueChanged(app, event)
           % Obter o texto colado no TextArea
            pastedText = app.PasteTextArea.Value;

            % Converter o cell array em uma única string
            pastedText = strjoin(pastedText, '\n');
            
            % Quebrar o texto colado em linhas e colunas
            rows = strsplit(pastedText, '\n'); % Separar por linhas
            numRows = length(rows);
            
            % Preparar a tabela de dados
            data = [];
            
            for i = 1:numRows
                % Quebrar cada linha em colunas usando separador de tabulação ou vírgula
                cols = strsplit(rows{i}, {'\t', ','});  
                
                % Converter os valores de string para número
                numericCols = str2double(cols);
                
                % Adicionar os valores à tabela de dados
                data = [data; numericCols];
            end
            
            % Verificar o tamanho dos dados e ajustar a tabela de inputs
            if size(data, 2) == 2
                app.InputTable.ColumnName = {'X1', 'X2'};
            elseif size(data, 2) == 3
                app.InputTable.ColumnName = {'X1', 'X2', 'X3'};
            end
            
            % Preencher a tabela de inputs com os dados processados
            app.InputTable.Data = data;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Superfície de Resposta')
            xlabel(app.grafico, 'X1')
            ylabel(app.grafico, 'X2')
            zlabel(app.grafico, 'Y')
            app.graficoResiduos = uiaxes(app.UIFigure);
            title(app.graficoResiduos, 'Resíduos')
            xlabel(app.graficoResiduos, 'Y')
            ylabel(app.graficoResiduos, 'Resíduos')
            zlabel(app.graficoResiduos, 'Z')
            app.LimitesLabel = uilabel(app.UIFigure);
            app.LimitesLabel.Text = 'Limites:';
            app.CondiesiniciaisLabel = uilabel(app.UIFigure);
            app.CondiesiniciaisLabel.Text = 'Condições iniciais:';
            app.CriarModeloButton = uibutton(app.UIFigure, 'push');
            app.CriarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CriarModeloButtonPushed2, true);
            app.CriarModeloButton.Text = 'Criar Modelo';
            app.GerarPlanejamentoButton = uibutton(app.UIFigure, 'push');
            app.GerarPlanejamentoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarPlanejamentoButtonPushed, true);
            app.GerarPlanejamentoButton.Text = 'Gerar Planejamento';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.TabeladeinputsLabel = uilabel(app.UIFigure);
            app.TabeladeinputsLabel.Text = 'Tabela de inputs ';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.Text = 'Limpar';
            app.FactorSelectionGroup = uibuttongroup(app.UIFigure);
            app.FactorSelectionGroup.SelectionChangedFcn = createCallbackFcn(app, @FactorSelectionGroupSelectionChanged, true);
            app.FactorSelectionGroup.Title = 'Fatores';
            app.TwoFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.TwoFactorsRadioButton.Text = '2 fatores';
            app.TwoFactorsRadioButton.Value = true;
            app.ThreeFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.ThreeFactorsRadioButton.Text = '3 fatores';
            app.BoundsTable = uitable(app.UIFigure);
            app.BoundsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BoundsTable.RowName = {};
            app.BoundsTable.ColumnEditable = true;
            app.InitialConditionsTable = uitable(app.UIFigure);
            app.InitialConditionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InitialConditionsTable.RowName = {};
            app.InitialConditionsTable.ColumnEditable = true;
            app.ColeInputsAquiTextAreaLabel = uilabel(app.UIFigure);
            app.ColeInputsAquiTextAreaLabel.Text = 'Cole Inputs Aqui';
            app.PasteTextArea = uitextarea(app.UIFigure);
            app.PasteTextArea.ValueChangedFcn = createCallbackFcn(app, @PasteTextAreaValueChanged, true);
            app.ModelCoefficientsTable = uitable(app.UIFigure);
            app.ModelCoefficientsTable.ColumnName = '';
            app.ModelCoefficientsTable.RowName = {};
            app.CoeficientesdoModeloLabel = uilabel(app.UIFigure);
            app.CoeficientesdoModeloLabel.Text = 'Coeficientes do Modelo';
            app.FTestTable = uitable(app.UIFigure);
            app.FTestTable.ColumnName = '';
            app.FTestTable.RowName = {'Valor F, Valor p'};
            app.R2Table = uitable(app.UIFigure);
            app.R2Table.ColumnName = '';
            app.R2Table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_regressao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end